classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        NField          matlab.ui.control.NumericEditField
        NLabel          matlab.ui.control.Label
        RunButton       matlab.ui.control.Button
        SignalDropDown  matlab.ui.control.DropDown
        SignalLabel     matlab.ui.control.Label
        PerfAxis        matlab.ui.control.UIAxes
        FreqAxis        matlab.ui.control.UIAxes
        TimeAxis        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            N = app.NField.Value;
            sigType = app.SignalDropDown.Value;
            
            if mod(log2(N)/2, 1) ~= 0
                uialert(app.UIFigure, 'For this project, please choose N as a power of 4 (e.g., 16, 64, 256).', 'Invalid N');
                return;
            end

            t = (0:N-1)';
            switch sigType
                case 'Sine'
                    x = sin(2 * pi * 2 * t / N); 
                case 'Square'
                    x = double(square(2 * pi * 2 * t / N));
                case 'Random'
                    x = rand(N, 1);
            end

            plot(app.TimeAxis, t, x, 'LineWidth', 1.5);
            title(app.TimeAxis, 'Time Domain Signal');
            xlabel(app.TimeAxis, 'Sample (n)');
            grid(app.TimeAxis, 'on');

            X_out = radix4_fft(x);
            
            stem(app.FreqAxis, abs(X_out), 'Filled');
            title(app.FreqAxis, 'DFT Magnitude |X[k]|');
            xlabel(app.FreqAxis, 'Frequency Bin (k)');
            grid(app.FreqAxis, 'on');


            test_Ns = [16, 64, 256, 1024]; 
            t_direct = zeros(length(test_Ns), 1);
            t_radix2 = zeros(length(test_Ns), 1);
            t_radix4 = zeros(length(test_Ns), 1);
            t_builtin = zeros(length(test_Ns), 1);
            
            app.RunButton.Enable = 'off';
            pause(0.1); 
            
            for i = 1:length(test_Ns)
                n_curr = test_Ns(i);
                x_test = rand(n_curr, 1);
                
                if n_curr <= 1024 
                    tic; direct_dft(x_test); t_direct(i) = toc;
                else
                    t_direct(i) = NaN; 
                end
                
                tic; radix2_fft(x_test); t_radix2(i) = toc;
                
                tic; radix4_fft(x_test); t_radix4(i) = toc;
                
                tic; fft(x_test); t_builtin(i) = toc;
            end
            
            hold(app.PerfAxis, 'off');
            plot(app.PerfAxis, test_Ns, t_direct, '-o', 'DisplayName', 'Direct DFT', 'LineWidth', 2);
            hold(app.PerfAxis, 'on');
            plot(app.PerfAxis, test_Ns, t_radix2, '-s', 'DisplayName', 'Radix-2', 'LineWidth', 2);
            plot(app.PerfAxis, test_Ns, t_radix4, '-^', 'DisplayName', 'Radix-4', 'LineWidth', 2);
            plot(app.PerfAxis, test_Ns, t_builtin, '--k', 'DisplayName', 'Matlab Built-in', 'LineWidth', 1);
            
            legend(app.PerfAxis, 'Location', 'best');
            title(app.PerfAxis, 'Execution Time vs N');
            xlabel(app.PerfAxis, 'Size N');
            ylabel(app.PerfAxis, 'Time (seconds)');
            set(app.PerfAxis, 'YScale', 'log'); 
            set(app.PerfAxis, 'XScale', 'log');
            grid(app.PerfAxis, 'on');
            
            app.RunButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeAxis = uiaxes(app.UIFigure);
            title(app.TimeAxis, 'Title')
            xlabel(app.TimeAxis, 'X')
            ylabel(app.TimeAxis, 'Y')
            zlabel(app.TimeAxis, 'Z')
            app.FreqAxis = uiaxes(app.UIFigure);
            title(app.FreqAxis, 'Title')
            xlabel(app.FreqAxis, 'X')
            ylabel(app.FreqAxis, 'Y')
            zlabel(app.FreqAxis, 'Z')
            app.PerfAxis = uiaxes(app.UIFigure);
            title(app.PerfAxis, 'Title')
            xlabel(app.PerfAxis, 'X')
            ylabel(app.PerfAxis, 'Y')
            zlabel(app.PerfAxis, 'Z')
            app.SignalLabel = uilabel(app.UIFigure);
            app.SignalLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.UIFigure);
            app.SignalDropDown.Items = {'Sine', 'Square', 'Random'};
            app.SignalDropDown.Value = 'Sine';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Calculate';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.NField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end